classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Nghiem                          matlab.ui.container.Tab
        NhapkhoangphanlinghiemEditField  matlab.ui.control.EditField
        NhapkhoangphanlinghiemEditFieldLabel  matlab.ui.control.Label
        ChonphuongphapDropDown          matlab.ui.control.DropDown
        ChonphuongphapDropDownLabel     matlab.ui.control.Label
        SolanlapEditField               matlab.ui.control.EditField
        SolanlapEditFieldLabel          matlab.ui.control.Label
        KetquaEditField                 matlab.ui.control.EditField
        KetquaEditFieldLabel            matlab.ui.control.Label
        NhapsaisochophepEditField       matlab.ui.control.EditField
        NhapsaisochophepEditFieldLabel  matlab.ui.control.Label
        NhapphuongtrinhEditField        matlab.ui.control.EditField
        NhapphuongtrinhEditFieldLabel   matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Noisuy                          matlab.ui.container.Tab
        KetquanoisuyEditField           matlab.ui.control.EditField
        KetquanoisuyEditFieldLabel      matlab.ui.control.Label
        ChonphuongphapDropDown_2        matlab.ui.control.DropDown
        ChonphuongphapDropDown_2Label   matlab.ui.control.Label
        KetquadathucnoisuyEditField     matlab.ui.control.EditField
        KetquadathucnoisuyEditFieldLabel  matlab.ui.control.Label
        NhapgiatricannoisuyEditField    matlab.ui.control.EditField
        NhapgiatricannoisuyEditFieldLabel  matlab.ui.control.Label
        DulieuyEditField                matlab.ui.control.EditField
        DulieuyEditFieldLabel           matlab.ui.control.Label
        DulieuxEditField                matlab.ui.control.EditField
        DulieuxEditFieldLabel           matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        Hoiquy                          matlab.ui.container.Tab
        ChonphuongphaphoiquyDropDown    matlab.ui.control.DropDown
        ChonphuongphaphoiquyDropDownLabel  matlab.ui.control.Label
        KetquadudoanEditField           matlab.ui.control.EditField
        KetquadudoanEditFieldLabel      matlab.ui.control.Label
        NhapgiatricandudoanEditField    matlab.ui.control.EditField
        NhapgiatricandudoanEditFieldLabel  matlab.ui.control.Label
        KetquaphuongtrinhhoiquyEditField  matlab.ui.control.EditField
        KetquaphuongtrinhhoiquyEditFieldLabel  matlab.ui.control.Label
        NhapdulieuyEditField            matlab.ui.control.EditField
        NhapdulieuyEditFieldLabel       matlab.ui.control.Label
        NhapdulieuxEditField            matlab.ui.control.EditField
        NhapdulieuxEditFieldLabel       matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        Daoham                          matlab.ui.container.Tab
        Tichphan                        matlab.ui.container.Tab
        Gioithieunhom                   matlab.ui.container.Tab
    end

    
    methods (Access = private)
        %Nghiem
function [a, b] = phanlihople(app, interval)
    % Phân tách chuỗi khoảng "a,b" thành hai giá trị số
    parts = split(interval, ',');
    if numel(parts) ~= 2
        error('Khoảng phân ly nghiệm không hợp lệ. Vui lòng nhập theo dạng "a,b".');
    end
    a = str2double(parts{1});
    b = str2double(parts{2});
    if isnan(a) || isnan(b) || a >= b
        error('Khoảng phân ly nghiệm không hợp lệ. Giá trị a phải nhỏ hơn b.');
    end
end

        
function [x, solanlap] = chiadoi(app, fx, a, b, saisochophep)
    fxi = str2func(['@(x)', fx]); % Chuyển chuỗi thành hàm số
    solanlap = 0; % Đếm số lần lặp
    while true
        c = (a + b) / 2; % Tính điểm giữa
        if fxi(a) * fxi(c) < 0
            b = c; % Nghiệm nằm trong khoảng [a, c]
        else
            a = c; % Nghiệm nằm trong khoảng [c, b]
        end
        solanlap = solanlap + 1; % Tăng số lần lặp
        if abs(a - b) < saisochophep
            break;
        end
    end
    x = c; % Trả về nghiệm
end


function [x1, n] = lap(app, fx, gx, a, b, saiso)
    fp_func = str2func(['@(x)', gx]); % Chuyển g(x) từ chuỗi thành hàm số
    x0 = (a + b) / 2; % Điểm khởi đầu
    n = 0; % Đếm số lần lặp
    while true
        x1 = fp_func(x0); % Tính g(x0)
        n = n + 1; % Tăng số lần lặp
        if abs(x1 - x0) < saiso
            break;
        end
        x0 = x1; % Cập nhật giá trị x0
    end
end


function [x, solanlap] = tieptuyen(app, fx, a, b, saiso)
    % Chuyển chuỗi fx thành symbolic để tính đạo hàm
    f_sym = str2sym(fx); % Biểu thức symbolic của f(x)
    df_sym = diff(f_sym); % Đạo hàm của f(x)
    
    % Chuyển f(x) và f'(x) thành các hàm số có thể gọi được
    fxi = matlabFunction(f_sym); 
    dfxi = matlabFunction(df_sym);

    x0 = (a + b) / 2; % Điểm khởi đầu
    solanlap = 0; % Đếm số lần lặp
    
    while true
        % Tính tiếp tuyến Newton
        x1 = x0 - fxi(x0) / dfxi(x0); 
        solanlap = solanlap + 1; % Tăng số lần lặp
        
        % Kiểm tra điều kiện hội tụ
        if abs(x1 - x0) < saiso
            break;
        end
        
        x0 = x1; % Cập nhật giá trị x0
    end
    
    x = x1; % Trả về nghiệm
end

%Noi suy
        function result = Lagrange(app, xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end

        % Newton interpolation function
        function result = NewtonInterpolation(app, xa, ya, x)
            n = length(xa);
            d = ya; % Divided differences table
            for i = 1:n
                for j = 1:i-1 
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            result = d(n);
            for i = n-1:-1:1
                result = result * (x - xa(i)) + d(i);
            end
        end

        % Polynomial for Newton interpolation
        function polynomial = NewtonInterpolationSyms(app, xa, ya)
            syms x;
            n = length(xa);
            d = ya; % Divided differences table
            for i = 1:n
                for j = 1:i-1
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            polynomial = d(n);
            for i = n-1:-1:1
                polynomial = expand(polynomial * (x - xa(i)) + d(i));
            end
        end

        % Polynomial for Lagrange interpolation
        function polynomial = LagrangeSyms(app, xa, ya)
            syms x;
            n = length(xa);
            polynomial = 0;
            for i = 1:n
                L = 1;
                for j = 1:n
                    if i ~= j
                        L = L * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                polynomial = expand(polynomial + ya(i) * L);
            end
        end

        %Hoi quy
        function [a1, a0, r2] = HoiQuyTuyenTinh(app, x, y)
            % Hoi quy tuyen tinh
            n = length(x);
            sumx = sum(x);
            sumy = sum(y);
            sumxy = sum(x.*y);
            sumx2 = sum(x.^2);
            xm = mean(x);
            ym = mean(y);

            a1 = (n*sumxy - sumx*sumy) / (n*sumx2 - sumx^2);
            a0 = ym - a1*xm;

            st = sum((y - ym).^2);
            sr = sum((y - (a1*x + a0)).^2);

            r2 = (st - sr) / st;            
        end

        function [a, b, r2] = HoiQuyHamMu(app, x, y)
            % Hoi quy ham mu: y = a*b^x
            X = log10(x);
            Y = log10(y);

            [A1, A0, r2] = app.HoiQuyTuyenTinh(X, Y);

            a = 10^A0;
            b = 10^A1;
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
     try
        % Lấy các giá trị từ giao diện
        fx = app.NhapphuongtrinhEditField.Value; % Hàm số f(x)
        interval = app.NhapkhoangphanlinghiemEditField.Value; % Khoảng phân ly nghiệm
        tolerance = str2double(app.NhapsaisochophepEditField.Value); % Sai số cho phép
        method = app.ChonphuongphapDropDown.Value; % Phương pháp được chọn

        % Kiểm tra nhập liệu
        if isnan(tolerance) || isempty(interval) || isempty(fx)
            error('Vui lòng nhập đầy đủ các thông tin cần thiết.');
        end

        % Parse khoảng phân ly nghiệm
        [a, b] = app.phanlihople(interval);

        % Xử lý theo phương pháp được chọn
        switch method
            case 'Chia doi'
                [result, iterations] = app.chiadoi(fx, a, b, tolerance);
            case 'Lap'
                % Tự động chuyển đổi f(x) thành g(x)
                syms x;
                f_sym = str2sym(fx); % Chuyển f(x) thành biểu thức symbolic
                g_sym = x - f_sym;  % Chuyển thành g(x) = x - f(x)
                gx = char(g_sym);   % Chuyển g(x) về chuỗi
                [result, iterations] = app.lap(fx, gx, a, b, tolerance);
            case 'Newton'
                [result, iterations] = app.tieptuyen(fx, a, b, tolerance);
            otherwise
                error('Phương pháp không hợp lệ.');
        end

        % Hiển thị kết quả
        app.KetquaEditField.Value = sprintf('%.6f', result);
        app.SolanlapEditField.Value = num2str(iterations);

        % Vẽ đồ thị hàm số
        fx_func = str2func(['@(x)', fx]);
        x_vals = linspace(a, b, 1000);
        y_vals = fx_func(x_vals);
        plot(app.UIAxes, x_vals, y_vals);
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'f(x)');
        title(app.UIAxes, 'Đồ thị hàm số');
    catch ME
        % Hiển thị lỗi cho người dùng
        uialert(app.UIFigure, ME.message, 'Lỗi');
    end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
    try
        % Đọc dữ liệu từ các trường
        xData = str2num(app.DulieuxEditField.Value); %#ok<ST2NM>
        yData = str2num(app.DulieuyEditField.Value); %#ok<ST2NM> %do chi nhap 1 so nen dung str2double
        xInterp = str2double(app.NhapgiatricannoisuyEditField.Value);
        method = app.ChonphuongphapDropDown_2.Value;

        % Kiểm tra dữ liệu
        if isempty(xData) || isempty(yData) || isempty(xInterp)
            uialert(app.UIFigure, 'Vui lòng nhập đủ dữ liệu!', 'Lỗi');
            return;
        end
        if length(xData) ~= length(yData)
            uialert(app.UIFigure, 'X và Y phải có cùng kích thước!', 'Lỗi');
            return;
        end

        % Nội suy theo phương pháp
        switch method
            case 'Lagrange'
                result = app.Lagrange(xData, yData, xInterp);
                polynomial = app.LagrangeSyms(xData, yData);
            case 'Newton'
                result = app.NewtonInterpolation(xData, yData, xInterp);
                polynomial = app.NewtonInterpolationSyms(xData, yData);
            otherwise
                error('Phương pháp không hợp lệ!');
        end

        % Hiển thị kết quả
        app.KetquadathucnoisuyEditField.Value = char(polynomial); % Đa thức
        app.KetquanoisuyEditField.Value = num2str(result); % Kết quả

        % Vẽ đồ thị
        fplot(app.UIAxes_2, matlabFunction(polynomial), [min(xData), max(xData)]);
        hold(app.UIAxes_2, 'on');
        scatter(app.UIAxes_2, xData, yData, 'filled');
        hold(app.UIAxes_2, 'off');
        grid(app.UIAxes_2, 'on');
        xlabel(app.UIAxes_2, 'x');
        ylabel(app.UIAxes_2, 'y');
        title(app.UIAxes_2, 'Interpolation and Data');
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end
            
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
             try
                % Lay du lieu tu giao dien
                x_input = app.NhapdulieuxEditField.Value;
                y_input = app.NhapdulieuyEditField.Value;
                predict_value = str2double(app.NhapgiatricandudoanEditField.Value);
                method = app.ChonphuongphaphoiquyDropDown.Value;

                % Chuyen doi chuoi du lieu thanh mang so
                x = str2num(x_input); %#ok<ST2NM>
                y = str2num(y_input); %#ok<ST2NM>

                % Kiem tra du lieu dau vao
                if isempty(x) || isempty(y) || length(x) ~= length(y)
                    error('Dữ liệu x và y không hợp lệ hoặc không khớp nhau.');
                end

                % Tinh toan hoi quy
                switch method
                    case 'Tuyen tinh'
                        [a1, a0, r2] = app.HoiQuyTuyenTinh(x, y);
                        result = a1 * predict_value + a0;
                        equation = sprintf('y = %.4f*x + %.4f', a1, a0);
                    case 'Ham mu'
                        [a, b, r2] = app.HoiQuyHamMu(x, y);
                        result = a * b^predict_value;
                        equation = sprintf('y = %.4f*%.4f^x', a, b);
                    otherwise
                        error('Phương pháp không hợp lệ.');
                end

                % Hien thi ket qua
                app.KetquadudoanEditField.Value = sprintf('%.4f', result);
                app.KetquaphuongtrinhhoiquyEditField.Value = equation;

                % Ve do thi
                scatter(app.UIAxes_3, x, y, 'filled'); % Ve diem du lieu
                hold(app.UIAxes_3, 'on');
                x_fit = linspace(min(x), max(x), 100);
                if strcmp(method, 'Tuyen tinh')
                    y_fit = a1 * x_fit + a0;
                else
                    y_fit = a * b.^x_fit;
                end
                plot(app.UIAxes_3, x_fit, y_fit, 'r-', 'LineWidth', 2);
                hold(app.UIAxes_3, 'off');

                xlabel(app.UIAxes_3, 'x');
                ylabel(app.UIAxes_3, 'y');
                grid(app.UIAxes_3, 'on');
            catch ME
                % Hien thi thong bao loi
                uialert(app.UIFigure, ME.message, 'Lỗi');
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Nghiem = uitab(app.TabGroup);
            app.Nghiem.Title = 'Tab';
            app.UIAxes = uiaxes(app.Nghiem);
            title(app.UIAxes, 'Do thi')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.Nghiem, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NhapphuongtrinhEditFieldLabel = uilabel(app.Nghiem);
            app.NhapphuongtrinhEditFieldLabel.Text = 'Nhap phuong trinh';
            app.NhapphuongtrinhEditField = uieditfield(app.Nghiem, 'text');
            app.NhapsaisochophepEditFieldLabel = uilabel(app.Nghiem);
            app.NhapsaisochophepEditFieldLabel.Text = 'Nhapsaisochophep';
            app.NhapsaisochophepEditField = uieditfield(app.Nghiem, 'text');
            app.KetquaEditFieldLabel = uilabel(app.Nghiem);
            app.KetquaEditFieldLabel.Text = 'Ket qua';
            app.KetquaEditField = uieditfield(app.Nghiem, 'text');
            app.SolanlapEditFieldLabel = uilabel(app.Nghiem);
            app.SolanlapEditFieldLabel.Text = 'So lan lap';
            app.SolanlapEditField = uieditfield(app.Nghiem, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.Nghiem);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.Nghiem);
            app.ChonphuongphapDropDown.Items = {'Chia doi', 'Lap', 'Newton', ''};
            app.ChonphuongphapDropDown.Value = 'Chia doi';
            app.NhapkhoangphanlinghiemEditFieldLabel = uilabel(app.Nghiem);
            app.NhapkhoangphanlinghiemEditFieldLabel.Text = 'Nhapkhoangphanlinghiem';
            app.NhapkhoangphanlinghiemEditField = uieditfield(app.Nghiem, 'text');
            app.Noisuy = uitab(app.TabGroup);
            app.Noisuy.Title = 'Tab2';
            app.UIAxes_2 = uiaxes(app.Noisuy);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.RunButton_2 = uibutton(app.Noisuy, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.DulieuxEditFieldLabel = uilabel(app.Noisuy);
            app.DulieuxEditFieldLabel.Text = 'Du lieu x';
            app.DulieuxEditField = uieditfield(app.Noisuy, 'text');
            app.DulieuyEditFieldLabel = uilabel(app.Noisuy);
            app.DulieuyEditFieldLabel.Text = 'Du lieu y';
            app.DulieuyEditField = uieditfield(app.Noisuy, 'text');
            app.NhapgiatricannoisuyEditFieldLabel = uilabel(app.Noisuy);
            app.NhapgiatricannoisuyEditFieldLabel.Text = 'Nhap gia tri can noi suy';
            app.NhapgiatricannoisuyEditField = uieditfield(app.Noisuy, 'text');
            app.KetquadathucnoisuyEditFieldLabel = uilabel(app.Noisuy);
            app.KetquadathucnoisuyEditFieldLabel.Text = 'Ket qua da thuc noi suy';
            app.KetquadathucnoisuyEditField = uieditfield(app.Noisuy, 'text');
            app.ChonphuongphapDropDown_2Label = uilabel(app.Noisuy);
            app.ChonphuongphapDropDown_2Label.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown_2 = uidropdown(app.Noisuy);
            app.ChonphuongphapDropDown_2.Items = {'Lagrange', 'Newton'};
            app.ChonphuongphapDropDown_2.Value = 'Lagrange';
            app.KetquanoisuyEditFieldLabel = uilabel(app.Noisuy);
            app.KetquanoisuyEditFieldLabel.Text = 'Ket qua noi suy';
            app.KetquanoisuyEditField = uieditfield(app.Noisuy, 'text');
            app.Hoiquy = uitab(app.TabGroup);
            app.Hoiquy.Title = 'Tab3';
            app.UIAxes_3 = uiaxes(app.Hoiquy);
            title(app.UIAxes_3, 'Do thi')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.RunButton_3 = uibutton(app.Hoiquy, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.NhapdulieuxEditFieldLabel = uilabel(app.Hoiquy);
            app.NhapdulieuxEditFieldLabel.Text = 'Nhap du lieu x';
            app.NhapdulieuxEditField = uieditfield(app.Hoiquy, 'text');
            app.NhapdulieuyEditFieldLabel = uilabel(app.Hoiquy);
            app.NhapdulieuyEditFieldLabel.Text = 'Nhap du lieu y';
            app.NhapdulieuyEditField = uieditfield(app.Hoiquy, 'text');
            app.KetquaphuongtrinhhoiquyEditFieldLabel = uilabel(app.Hoiquy);
            app.KetquaphuongtrinhhoiquyEditFieldLabel.Text = 'Ket qua phuong trinh hoi quy';
            app.KetquaphuongtrinhhoiquyEditField = uieditfield(app.Hoiquy, 'text');
            app.NhapgiatricandudoanEditFieldLabel = uilabel(app.Hoiquy);
            app.NhapgiatricandudoanEditFieldLabel.Text = 'Nhap gia tri can du doan';
            app.NhapgiatricandudoanEditField = uieditfield(app.Hoiquy, 'text');
            app.KetquadudoanEditFieldLabel = uilabel(app.Hoiquy);
            app.KetquadudoanEditFieldLabel.Text = 'Ket qua du doan';
            app.KetquadudoanEditField = uieditfield(app.Hoiquy, 'text');
            app.ChonphuongphaphoiquyDropDownLabel = uilabel(app.Hoiquy);
            app.ChonphuongphaphoiquyDropDownLabel.Text = 'Chon phuong phap hoi quy';
            app.ChonphuongphaphoiquyDropDown = uidropdown(app.Hoiquy);
            app.ChonphuongphaphoiquyDropDown.Items = {'Tuyen tinh', 'Ham mu'};
            app.ChonphuongphaphoiquyDropDown.Value = 'Tuyen tinh';
            app.Daoham = uitab(app.TabGroup);
            app.Daoham.Title = 'Tab4';
            app.Tichphan = uitab(app.TabGroup);
            app.Tichphan.Title = 'Tab5';
            app.Gioithieunhom = uitab(app.TabGroup);
            app.Gioithieunhom.Title = 'Tab6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end