classdef Doan1_giaithich < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RunButton                       matlab.ui.control.Button
        NhapphuongtrinhEditFieldLabel   matlab.ui.control.Label
        NhapphuongtrinhEditField        matlab.ui.control.EditField
        NhapsaisochophepEditFieldLabel  matlab.ui.control.Label
        NhapsaisochophepEditField       matlab.ui.control.EditField
        KetquaEditFieldLabel            matlab.ui.control.Label
        KetquaEditField                 matlab.ui.control.EditField
        SolanlapEditFieldLabel          matlab.ui.control.Label
        SolanlapEditField               matlab.ui.control.EditField
        ChonphuongphapDropDownLabel     matlab.ui.control.Label
        ChonphuongphapDropDown          matlab.ui.control.DropDown
        NhapkhoangphanlinghiemEditField_2Label  matlab.ui.control.Label
        NhapkhoangphanlinghiemEditField  matlab.ui.control.EditField
    end

    
    methods (Access = private)
function [a, b] = phanlihople(app, interval)
    % Phân tách chuỗi khoảng "a,b" thành hai giá trị số
    parts = split(interval, ',');
    if numel(parts) ~= 2 %Kiểm tra xem chuỗi interval có đúng định dạng chứa hai phần (dùng dấu phẩy ngăn cách). Nếu không, báo lỗi.
        error('Khoảng phân ly nghiệm không hợp lệ. Vui lòng nhập theo dạng "a,b".');
    end
    %Chuyển các chuỗi trong parts thành số thực (str2double).
    a = str2double(parts{1}); 
    b = str2double(parts{2});
    %Kiểm tra các giá trị a và b:
% isnan: Kiểm tra xem giá trị có phải là số hợp lệ không.
% Kiểm tra điều kiện a < b. Nếu không thỏa mãn, báo lỗi.
    if isnan(a) || isnan(b) || a >= b
        error('Khoảng phân ly nghiệm không hợp lệ. Giá trị a phải nhỏ hơn b.');
    end
end

        
function [x, solanlap] = chiadoi(app, fx, a, b, saisochophep) %hàm chia đôi
    fxi = str2func(['@(x)', fx]); % Chuyển chuỗi thành hàm số
    solanlap = 0; % Đếm số lần lặp
    while true
        c = (a + b) / 2; % Tính điểm giữa
        if fxi(a) * fxi(c) < 0
            b = c; % Nghiệm nằm trong khoảng [a, c]
        else
            a = c; % Nghiệm nằm trong khoảng [c, b]
        end
        solanlap = solanlap + 1; % Tăng số lần lặp
        if abs(a - b) < saisochophep % Nếu trị tuyệt đối của (a-b) bé hơn saisochophep
            break;
        end
    end
    x = c; % Trả về nghiệm
end


function [x1, n] = lap(app, fx, gx, a, b, saiso) % hàm lặp
    fp_func = str2func(['@(x)', gx]); % Chuyển g(x) từ chuỗi thành hàm số
    x0 = (a + b) / 2; % Điểm khởi đầu
    n = 0; % Đếm số lần lặp
    while true
        x1 = fp_func(x0); % Tính g(x0)
        n = n + 1; % Tăng số lần lặp
        if abs(x1 - x0) < saiso
            break;
        end
        x0 = x1; % Cập nhật giá trị x0
    end
end


        function [x, solanlap] = tieptuyen(app, fx, a, b, saiso) % hàm tiếp tuyến
    % Chuyển chuỗi fx thành symbolic để tính đạo hàm
    f_sym = str2sym(fx); % Biểu thức symbolic của f(x)
    df_sym = diff(f_sym); % Đạo hàm của f(x)
    
    % Chuyển f(x) và f'(x) thành các hàm số có thể gọi được
    fxi = matlabFunction(f_sym); 
    dfxi = matlabFunction(df_sym);

    x0 = (a + b) / 2; % Điểm khởi đầu
    solanlap = 0; % Đếm số lần lặp
    
    while true
        % Tính tiếp tuyến Newton
        x1 = x0 - fxi(x0) / dfxi(x0); 
        solanlap = solanlap + 1; % Tăng số lần lặp
        
        % Kiểm tra điều kiện hội tụ
        if abs(x1 - x0) < saiso
            break;
        end
        
        x0 = x1; % Cập nhật giá trị x0
    end
    
    x = x1; % Trả về nghiệm
end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

    try
        % Lấy các giá trị từ giao diện
        fx = app.NhapphuongtrinhEditField.Value; % Hàm số f(x)
        interval = app.NhapkhoangphanlinghiemEditField.Value; % Khoảng phân ly nghiệm
        tolerance = str2double(app.NhapsaisochophepEditField.Value); % Sai số cho phép
        method = app.ChonphuongphapDropDown.Value; % Phương pháp được chọn

        % Kiểm tra nhập liệu
        if isnan(tolerance) || isempty(interval) || isempty(fx)
            error('Vui lòng nhập đầy đủ các thông tin cần thiết.');
        end

        % Parse khoảng phân ly nghiệm
        [a, b] = app.phanlihople(interval);

        % Xử lý theo phương pháp được chọn
        switch method
            case 'Chia doi'
                [result, iterations] = app.chiadoi(fx, a, b, tolerance);
            case 'Lap'
                % Tự động chuyển đổi f(x) thành g(x)
                syms x;
                f_sym = str2sym(fx); % Chuyển f(x) thành biểu thức symbolic
                g_sym = x - f_sym;  % Chuyển thành g(x) = x - f(x)
                gx = char(g_sym);   % Chuyển g(x) về chuỗi
                [result, iterations] = app.lap(fx, gx, a, b, tolerance);
            case 'Newton'
                [result, iterations] = app.tieptuyen(fx, a, b, tolerance);
            otherwise
                error('Phương pháp không hợp lệ.');
        end

        % Hiển thị kết quả
        app.KetquaEditField.Value = sprintf('%.6f', result);
        app.SolanlapEditField.Value = num2str(iterations);

        % Vẽ đồ thị hàm số
        fx_func = str2func(['@(x)', fx]);%  Chuyển chuỗi biểu thức f(x) nhập vào từ giao diện) thành một hàm số MATLAB có thể tính toán
        x_vals = linspace(a, b, 1000); %tạo mảng giá trị x là các giá trị dùng để tính toán f(x) và vẽ đồ thị
        y_vals = fx_func(x_vals); %Tính giá trị f(x) cho mỗi giá trị x trong x_vals
        plot(app.UIAxes, x_vals, y_vals);
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'f(x)');
        title(app.UIAxes, 'Đồ thị hàm số');
    catch ME
        % Hiển thị lỗi cho người dùng
        uialert(app.UIFigure, ME.message, 'Lỗi');
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NhapphuongtrinhEditFieldLabel = uilabel(app.UIFigure);
            app.NhapphuongtrinhEditFieldLabel.Text = 'Nhap phuong trinh';
            app.NhapphuongtrinhEditField = uieditfield(app.UIFigure, 'text');
            app.NhapphuongtrinhEditField.Value = 'x-sin(x)-0.25';
            app.NhapsaisochophepEditFieldLabel = uilabel(app.UIFigure);
            app.NhapsaisochophepEditFieldLabel.Text = 'Nhapsaisochophep';
            app.NhapsaisochophepEditField = uieditfield(app.UIFigure, 'text');
            app.NhapsaisochophepEditField.Value = '0.005';
            app.KetquaEditFieldLabel = uilabel(app.UIFigure);
            app.KetquaEditFieldLabel.Text = 'Ket qua';
            app.KetquaEditField = uieditfield(app.UIFigure, 'text');
            app.SolanlapEditFieldLabel = uilabel(app.UIFigure);
            app.SolanlapEditFieldLabel.Text = 'So lan lap';
            app.SolanlapEditField = uieditfield(app.UIFigure, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.UIFigure);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Chia doi', 'Lap', 'Newton', ''};
            app.ChonphuongphapDropDown.Value = 'Chia doi';
            app.NhapkhoangphanlinghiemEditField_2Label = uilabel(app.UIFigure);
            app.NhapkhoangphanlinghiemEditField_2Label.Text = 'Nhapkhoangphanlinghiem';
            app.NhapkhoangphanlinghiemEditField = uieditfield(app.UIFigure, 'text');
            app.NhapkhoangphanlinghiemEditField.Value = '[-1,2]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan1_giaithich

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end