classdef Doan2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        RunButton                     matlab.ui.control.Button
        DulieuxEditFieldLabel         matlab.ui.control.Label
        DulieuxEditField              matlab.ui.control.EditField
        DulieuyEditFieldLabel         matlab.ui.control.Label
        DulieuyEditField              matlab.ui.control.EditField
        NhapgiatricannoisuyEditFieldLabel  matlab.ui.control.Label
        NhapgiatricannoisuyEditField  matlab.ui.control.EditField
        KetquadathucnoisuyEditFieldLabel  matlab.ui.control.Label
        KetquadathucnoisuyEditField   matlab.ui.control.EditField
        ChonphuongphapDropDownLabel   matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        KetquanoisuyEditFieldLabel    matlab.ui.control.Label
        KetquanoisuyEditField         matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        % Lagrange interpolation function
        function result = Lagrange(app, xa, ya, x)
            n = length(xa);
            sum = 0;
            for i = 1:n
                product = ya(i);
                for j = 1:n
                    if i ~= j
                        product = product * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                sum = sum + product;
            end
            result = sum;
        end

        % Newton interpolation function
        function result = NewtonInterpolation(app, xa, ya, x)
            n = length(xa);
            d = ya; % Divided differences table
            for i = 1:n
                for j = 1:i-1 
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            result = d(n);
            for i = n-1:-1:1
                result = result * (x - xa(i)) + d(i);
            end
        end

        % Polynomial for Newton interpolation
        function polynomial = NewtonInterpolationSyms(app, xa, ya)
            syms x;
            n = length(xa);
            d = ya; % Divided differences table
            for i = 1:n
                for j = 1:i-1
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            polynomial = d(n);
            for i = n-1:-1:1
                polynomial = expand(polynomial * (x - xa(i)) + d(i));
            end
        end

        % Polynomial for Lagrange interpolation
        function polynomial = LagrangeSyms(app, xa, ya)
            syms x;
            n = length(xa);
            polynomial = 0;
            for i = 1:n
                L = 1;
                for j = 1:n
                    if i ~= j
                        L = L * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                polynomial = expand(polynomial + ya(i) * L);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    try
        % Đọc dữ liệu từ các trường
        xData = str2num(app.DulieuxEditField.Value); %#ok<ST2NM>
        yData = str2num(app.DulieuyEditField.Value); %#ok<ST2NM>
        xInterp = str2double(app.NhapgiatricannoisuyEditField.Value);
        method = app.ChonphuongphapDropDown.Value;

        % Kiểm tra dữ liệu
        if isempty(xData) || isempty(yData) || isempty(xInterp)
            uialert(app.UIFigure, 'Vui lòng nhập đủ dữ liệu!', 'Lỗi');
            return;
        end
        if length(xData) ~= length(yData)
            uialert(app.UIFigure, 'X và Y phải có cùng kích thước!', 'Lỗi');
            return;
        end

        % Nội suy theo phương pháp
        switch method
            case 'Lagrange'
                result = app.Lagrange(xData, yData, xInterp);
                polynomial = app.LagrangeSyms(xData, yData);
            case 'Newton'
                result = app.NewtonInterpolation(xData, yData, xInterp);
                polynomial = app.NewtonInterpolationSyms(xData, yData);
            otherwise
                error('Phương pháp không hợp lệ!');
        end

        % Hiển thị kết quả
        app.KetquadathucnoisuyEditField.Value = char(polynomial); % Đa thức
        app.KetquanoisuyEditField.Value = num2str(result); % Kết quả

        % Vẽ đồ thị
        fplot(app.UIAxes, matlabFunction(polynomial), [min(xData), max(xData)]);
        hold(app.UIAxes, 'on');
        scatter(app.UIAxes, xData, yData, 'filled');
        hold(app.UIAxes, 'off');
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'y');
        title(app.UIAxes, 'Interpolation and Data');
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DulieuxEditFieldLabel = uilabel(app.UIFigure);
            app.DulieuxEditFieldLabel.Text = 'Du lieu x';
            app.DulieuxEditField = uieditfield(app.UIFigure, 'text');
            app.DulieuyEditFieldLabel = uilabel(app.UIFigure);
            app.DulieuyEditFieldLabel.Text = 'Du lieu y';
            app.DulieuyEditField = uieditfield(app.UIFigure, 'text');
            app.NhapgiatricannoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgiatricannoisuyEditFieldLabel.Text = 'Nhap gia tri can noi suy';
            app.NhapgiatricannoisuyEditField = uieditfield(app.UIFigure, 'text');
            app.KetquadathucnoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KetquadathucnoisuyEditFieldLabel.Text = 'Ket qua da thuc noi suy';
            app.KetquadathucnoisuyEditField = uieditfield(app.UIFigure, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.UIFigure);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Lagrange', 'Newton'};
            app.ChonphuongphapDropDown.Value = 'Lagrange';
            app.KetquanoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KetquanoisuyEditFieldLabel.Text = 'Ket qua noi suy';
            app.KetquanoisuyEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end