classdef Doan2_giaithich < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        RunButton                     matlab.ui.control.Button
        DulieuxEditFieldLabel         matlab.ui.control.Label
        DulieuxEditField              matlab.ui.control.EditField
        DulieuyEditFieldLabel         matlab.ui.control.Label
        DulieuyEditField              matlab.ui.control.EditField
        NhapgiatricannoisuyEditFieldLabel  matlab.ui.control.Label
        NhapgiatricannoisuyEditField  matlab.ui.control.EditField
        KetquadathucnoisuyEditFieldLabel  matlab.ui.control.Label
        KetquadathucnoisuyEditField   matlab.ui.control.EditField
        ChonphuongphapDropDownLabel   matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        KetquanoisuyEditFieldLabel    matlab.ui.control.Label
        KetquanoisuyEditField         matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        % Lagrange interpolation function
        % hàm nội suy Lagrange với xa là mảng chứa các giá trị x đã biết
        % ya là mảng chứa các giá trị y tương ứng với xa
        % x là điểm cần nội suy
        function result = Lagrange(app, xa, ya, x) 
            n = length(xa); % lấy số lượng điểm n trong xa
            sum = 0; %Khởi tạo biến tổng để lưu kết quả nội suy
            for i = 1:n % Vòng lặp qua từng điểm i trong xa
                product = ya(i);% Khởi tạo giá trị tích ban đầu bằng ya(i),tương ứng với hệ số yi,trong công thức Lagrange
                for j = 1:n % Tính Li(x),với mỗi j khác i,nhân thêm hệ số (x-xj)/(xi-xj)
                    if i ~= j
                        product = product * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                sum = sum + product; % cộng yi.Li(x) vào tổng
            end
            result = sum; %Trả về kết quả nội suy
        end

        % Newton interpolation function
        % Tính giá trị nội suy tại x bằng phương pháp nội suy Newton
        function result = NewtonInterpolation(app, xa, ya, x)
            n = length(xa); % lấy số lượng điểm n
            d = ya; % gán d là mảng chứa các giá trị y ban đầu,sẽ được dùng để tính bảng sai phân chia
            for i = 1:n % tính bảng sai phân chia,lưu trữ trực tiếp vào d
                for j = 1:i-1 
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            result = d(n); % Khởi tạo giá trị kết quả bằng hệ số cao nhất dn
            for i = n-1:-1:1 %Tính giá trị nội suy theo dạng đa thức Newton.
                result = result * (x - xa(i)) + d(i);
            end
        end

        % Polynomial for Newton interpolation
        % Tính và trả về đa thức nội suy Newton ở dạng symbolic
        function polynomial = NewtonInterpolationSyms(app, xa, ya)
            syms x;
            n = length(xa);
            d = ya; % Divided differences table
            for i = 1:n
                for j = 1:i-1
                    d(i)=(d(j) - d(i))/(xa(j) - xa(i)); 
                end
            end
            polynomial = d(n); % Tính đa thức nội suy Newton
            for i = n-1:-1:1
                % expand: Mở rộng đa thức để biểu diễn rõ ràng hơn
                polynomial = expand(polynomial * (x - xa(i)) + d(i));
            end
        end

        % Polynomial for Lagrange interpolation
        % Tính và trả về đa thức nội suy Lagrange ở dạng symbolic
        function polynomial = LagrangeSyms(app, xa, ya)
            syms x;
            n = length(xa);
            polynomial = 0;
            % tính Li(x) theo công thức Lagrange
            % nhân thêm hệ số yi
            % cộng các thành phần yi.Li(x) vào tổng
            
            for i = 1:n
                L = 1;
                for j = 1:n
                    if i ~= j
                        L = L * (x - xa(j)) / (xa(i) - xa(j));
                    end
                end
                polynomial = expand(polynomial + ya(i) * L);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
    try
        % Đọc dữ liệu từ các trường
        xData = str2num(app.DulieuxEditField.Value); % Chuyển chuỗi dữ liệu từ ô nhập DulieuxEditField thành mảng số
        yData = str2num(app.DulieuyEditField.Value); % Chuyển chuỗi dữ liệu từ ô nhập DulieuyEditField thành mảng số
        xInterp = str2double(app.NhapgiatricannoisuyEditField.Value); % Chuyển giá trị nhập ở ô NhapgiatricannoisuyEditField thành số thực 
        method = app.ChonphuongphapDropDown.Value; % Lấy giá trị của phương pháp được chọn từ dropdown menu

        % Kiểm tra dữ liệu
        if isempty(xData) || isempty(yData) || isempty(xInterp)
            %Kiểm tra xem mảng xData, yData hoặc xInterp có rỗng không
            uialert(app.UIFigure, 'Vui lòng nhập đủ dữ liệu!', 'Lỗi');
            return;
        end
        if length(xData) ~= length(yData)
            % Kiểm tra kích thước của mảng x và y có khớp nhau không
            uialert(app.UIFigure, 'X và Y phải có cùng kích thước!', 'Lỗi');
            return;
        end

        % Nội suy theo phương pháp
        switch method
            %  Lựa chọn phương pháp nội suy dựa trên giá trị method
            case 'Lagrange'
                result = app.Lagrange(xData, yData, xInterp); 
                % Tính giá trị nội suy tại x
                polynomial = app.LagrangeSyms(xData, yData);
                % Tính đa thức nội suy
            case 'Newton'
                result = app.NewtonInterpolation(xData, yData, xInterp);
                % Tính giá trị nội suy tại x
                polynomial = app.NewtonInterpolationSyms(xData, yData);
                % Tính đa thức nội suy
            otherwise
                % Báo lỗi nếu phương pháp không hợp lệ
                error('Phương pháp không hợp lệ!');
        end

        % Hiển thị kết quả
        app.KetquadathucnoisuyEditField.Value = char(polynomial); % Đa thức
        % Hiển thị biểu thức đa thức nội suy ở dạng ký tự.
        app.KetquanoisuyEditField.Value = num2str(result); % Kết quả
        %Hiển thị giá trị nội suy

        % Vẽ đồ thị
        fplot(app.UIAxes, matlabFunction(polynomial), [min(xData), max(xData)]);
        % Vẽ đồ thị của đa thức nội suy trên khoảng từ min(xData) đến max(xData)
        hold(app.UIAxes, 'on');
        scatter(app.UIAxes, xData, yData, 'filled'); % Hiển thị các điểm dữ liệu (xi,yi) dưới dạng các chấm tròn
        hold(app.UIAxes, 'off');
        grid(app.UIAxes, 'on'); % bật lưới
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'y');
        title(app.UIAxes, 'Interpolation and Data');
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
        % Nếu xảy ra lỗi, hiển thị thông báo lỗi với nội dung trong ME.message.

    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DulieuxEditFieldLabel = uilabel(app.UIFigure);
            app.DulieuxEditFieldLabel.Text = 'Du lieu x';
            app.DulieuxEditField = uieditfield(app.UIFigure, 'text');
            app.DulieuxEditField.Value = '0.1 , 0.2 , 0.3 , 0.4';
            app.DulieuyEditFieldLabel = uilabel(app.UIFigure);
            app.DulieuyEditFieldLabel.Text = 'Du lieu y';
            app.DulieuyEditField = uieditfield(app.UIFigure, 'text');
            app.DulieuyEditField.Value = '0.09983 0.19867 0.29552 0.38942';
            app.NhapgiatricannoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgiatricannoisuyEditFieldLabel.Text = 'Nhap gia tri can noi suy';
            app.NhapgiatricannoisuyEditField = uieditfield(app.UIFigure, 'text');
            app.NhapgiatricannoisuyEditField.Value = '0.14';
            app.KetquadathucnoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KetquadathucnoisuyEditFieldLabel.Text = 'Ket qua da thuc noi suy';
            app.KetquadathucnoisuyEditField = uieditfield(app.UIFigure, 'text');
            app.ChonphuongphapDropDownLabel = uilabel(app.UIFigure);
            app.ChonphuongphapDropDownLabel.Text = 'Chon phuong phap';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Lagrange', 'Newton'};
            app.ChonphuongphapDropDown.Value = 'Newton';
            app.KetquanoisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KetquanoisuyEditFieldLabel.Text = 'Ket qua noi suy';
            app.KetquanoisuyEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan2_giaithich

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end