classdef Doan3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChonphuongphaphoiquyDropDown  matlab.ui.control.DropDown
        ChonphuongphaphoiquyDropDownLabel  matlab.ui.control.Label
        KetquadudoanEditField         matlab.ui.control.EditField
        KetquadudoanEditFieldLabel    matlab.ui.control.Label
        NhapgiatricandudoanEditField  matlab.ui.control.EditField
        NhapgiatricandudoanEditFieldLabel  matlab.ui.control.Label
        KetquaphuongtrinhhoiquyEditField  matlab.ui.control.EditField
        KetquaphuongtrinhhoiquyEditFieldLabel  matlab.ui.control.Label
        NhapdulieuyEditField          matlab.ui.control.EditField
        NhapdulieuyEditFieldLabel     matlab.ui.control.Label
        NhapdulieuxEditField          matlab.ui.control.EditField
        NhapdulieuxEditFieldLabel     matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [a1, a0, r2] = HoiQuyTuyenTinh(app, x, y)
            % Hoi quy tuyen tinh
            n = length(x);
            sumx = sum(x);
            sumy = sum(y);
            sumxy = sum(x.*y);
            sumx2 = sum(x.^2);
            xm = mean(x);
            ym = mean(y);

            a1 = (n*sumxy - sumx*sumy) / (n*sumx2 - sumx^2);
            a0 = ym - a1*xm;

            st = sum((y - ym).^2);
            sr = sum((y - (a1*x + a0)).^2);

            r2 = (st - sr) / st;            
        end

        function [a, b, r2] = HoiQuyHamMu(app, x, y)
            % Hoi quy ham mu: y = a*b^x
            X = log10(x);
            Y = log10(y);

            [A1, A0, r2] = app.HoiQuyTuyenTinh(X, Y);

            a = 10^A0;
            b = 10^A1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             try
                % Lay du lieu tu giao dien
                x_input = app.NhapdulieuxEditField.Value;
                y_input = app.NhapdulieuyEditField.Value;
                predict_value = str2double(app.NhapgiatricandudoanEditField.Value);
                method = app.ChonphuongphaphoiquyDropDown.Value;

                % Chuyen doi chuoi du lieu thanh mang so
                x = str2num(x_input); %#ok<ST2NM>
                y = str2num(y_input); %#ok<ST2NM>

                % Kiem tra du lieu dau vao
                if isempty(x) || isempty(y) || length(x) ~= length(y)
                    error('Dữ liệu x và y không hợp lệ hoặc không khớp nhau.');
                end

                % Tinh toan hoi quy
                switch method
                    case 'Tuyen tinh'
                        [a1, a0, r2] = app.HoiQuyTuyenTinh(x, y);
                        result = a1 * predict_value + a0;
                        equation = sprintf('y = %.4f*x + %.4f', a1, a0);
                    case 'Ham mu'
                        [a, b, r2] = app.HoiQuyHamMu(x, y);
                        result = a * b^predict_value;
                        equation = sprintf('y = %.4f*%.4f^x', a, b);
                    otherwise
                        error('Phương pháp không hợp lệ.');
                end

                % Hien thi ket qua
                app.KetquadudoanEditField.Value = sprintf('%.4f', result);
                app.KetquaphuongtrinhhoiquyEditField.Value = equation;

                % Ve do thi
                scatter(app.UIAxes, x, y, 'filled'); % Ve diem du lieu
                hold(app.UIAxes, 'on');
                x_fit = linspace(min(x), max(x), 100);
                if strcmp(method, 'Tuyen tinh')
                    y_fit = a1 * x_fit + a0;
                else
                    y_fit = a * b.^x_fit;
                end
                plot(app.UIAxes, x_fit, y_fit, 'r-', 'LineWidth', 2);
                hold(app.UIAxes, 'off');

                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'y');
                grid(app.UIAxes, 'on');
            catch ME
                % Hien thi thong bao loi
                uialert(app.UIFigure, ME.message, 'Lỗi');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Do thi')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NhapdulieuxEditFieldLabel = uilabel(app.UIFigure);
            app.NhapdulieuxEditFieldLabel.Text = 'Nhap du lieu x';
            app.NhapdulieuxEditField = uieditfield(app.UIFigure, 'text');
            app.NhapdulieuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhapdulieuyEditFieldLabel.Text = 'Nhap du lieu y';
            app.NhapdulieuyEditField = uieditfield(app.UIFigure, 'text');
            app.KetquaphuongtrinhhoiquyEditFieldLabel = uilabel(app.UIFigure);
            app.KetquaphuongtrinhhoiquyEditFieldLabel.Text = 'Ket qua phuong trinh hoi quy';
            app.KetquaphuongtrinhhoiquyEditField = uieditfield(app.UIFigure, 'text');
            app.NhapgiatricandudoanEditFieldLabel = uilabel(app.UIFigure);
            app.NhapgiatricandudoanEditFieldLabel.Text = 'Nhap gia tri can du doan';
            app.NhapgiatricandudoanEditField = uieditfield(app.UIFigure, 'text');
            app.KetquadudoanEditFieldLabel = uilabel(app.UIFigure);
            app.KetquadudoanEditFieldLabel.Text = 'Ket qua du doan';
            app.KetquadudoanEditField = uieditfield(app.UIFigure, 'text');
            app.ChonphuongphaphoiquyDropDownLabel = uilabel(app.UIFigure);
            app.ChonphuongphaphoiquyDropDownLabel.Text = 'Chon phuong phap hoi quy';
            app.ChonphuongphaphoiquyDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphaphoiquyDropDown.Items = {'Tuyen tinh', 'Ham mu'};
            app.ChonphuongphaphoiquyDropDown.Value = 'Tuyen tinh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Doan3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end